classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        Calculate              matlab.ui.control.Button           % Calculate
        LabelNumericEditField  matlab.ui.control.Label            % Mass(kg)
        MassEditField          matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField2 matlab.ui.control.Label            % Acc  (m...
        AccEditField2          matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % Weight (N)
        ForceEditField3        matlab.ui.control.NumericEditField % [-Inf Inf]
        Panel                  matlab.ui.container.Panel         
        Label                  matlab.ui.control.Label            % Newtons...
        Lamp                   matlab.ui.control.Lamp            
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Calculate button pushed function
        function CalculateButtonPushed(app)
            mass = app.MassEditField.Value;
            acc = app.AccEditField2.Value;
            Force = mass*acc;
            app.ForceEditField3.Value = Force;
            
            if Force > 0 && Force <= 500
                app.Lamp.Color = 'g';
            elseif Force > 500 && Force <= 1000
                app.Lamp.Color = [0.93,0.69,0.13]; %Orange
            else
                app.Lamp.Color = 'red';
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Units = 'pixels';
            app.Calculate = uibutton(app.Panel, 'push');
            app.Calculate.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed);
            app.Calculate.Text = 'Calculate';
            app.LabelNumericEditField = uilabel(app.Panel);
            app.LabelNumericEditField.Text = 'Mass(kg)';
            app.MassEditField = uieditfield(app.Panel, 'numeric');
            app.LabelNumericEditField2 = uilabel(app.Panel);
            app.LabelNumericEditField2.Text = 'Acc  (m/s^2)';
            app.AccEditField2 = uieditfield(app.Panel, 'numeric');
            app.LabelNumericEditField3 = uilabel(app.Panel);
            app.LabelNumericEditField3.Text = 'Weight (N)';
            app.ForceEditField3 = uieditfield(app.Panel, 'numeric');
            app.Lamp = uilamp(app.Panel);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Newtons Calculator ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end