classdef App2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        Lamp                  matlab.ui.control.Lamp            
        Label                 matlab.ui.control.Label            % Not Active
        LabelNumericEditField matlab.ui.control.Label            % Sensor I...
        SensorInput           matlab.ui.control.NumericEditField % [-Inf Inf]
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % SensorInput value changed function
        function SensorInputValueChanged(app)
            value = app.SensorInput.Value;
            
            % 0 Not Active 
            % 1 Active
            % 2 Error!
            
            switch value
                case 0
                    app.Lamp.Color = [ 0.9, 0.9, 0.9]; % Gray
                    app.Label.Text = 'Not Active';
                case 1 
                    app.Lamp.Color = 'g';
                    app.Label.Text = 'Active';
                case 2
                    app.Lamp.Color = 'r';
                    app.Label.Text = 'Error!';
                otherwise 
                    app.Lamp.Color = [0 0 0];
                    app.Label.Text = 'State Unown';
            end
                    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Not Active';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Sensor Input';
            app.SensorInput = uieditfield(app.UIFigure, 'numeric');
            app.SensorInput.ValueChangedFcn = createCallbackFcn(app, @SensorInputValueChanged);
        end
    end

    methods (Access = public)

        % Construct app
        function app = App2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end