classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure               % UI Figure
        LabelSwitch       matlab.ui.control.Label        % Switch
        Switch            matlab.ui.control.Switch       % Off, On
        LabelLamp         matlab.ui.control.Label        % Lamp
        Lamp              matlab.ui.control.Lamp        
        LabelRockerSwitch matlab.ui.control.Label        % Rocker Switch
        RockerSwitch      matlab.ui.control.RockerSwitch % Off, On
        LabelToggleSwitch matlab.ui.control.Label        % Toggle Switch
        ToggleSwitch      matlab.ui.control.ToggleSwitch % Off, On
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Switch value changed function
        function SwitchValueChanged(app)
            value = app.Switch.Value;
            
            if strcmp(value , 'On')
                app.Lamp.Color = 'g'
            else 
                app.Lamp.Color = 'r'
            end
        end

        % RockerSwitch value changed function
        function RockerSwitchValueChanged(app)
            value = app.RockerSwitch.Value;
            if strcmp(value , 'On')
                app.Lamp.Color = 'g'
            else 
                app.Lamp.Color = 'r'
            end
        end

        % ToggleSwitch value changed function
        function ToggleSwitchValueChanged(app)
            value = app.ToggleSwitch.Value;
            if strcmp(value , 'On')
                app.Lamp.Color = 'g'
            else 
                app.Lamp.Color = 'r'
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelSwitch = uilabel(app.UIFigure);
            app.LabelSwitch.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged);
            app.LabelLamp = uilabel(app.UIFigure);
            app.LabelLamp.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.LabelRockerSwitch = uilabel(app.UIFigure);
            app.LabelRockerSwitch.Text = 'Rocker Switch';
            app.RockerSwitch = uiswitch(app.UIFigure, 'rocker');
            app.RockerSwitch.ValueChangedFcn = createCallbackFcn(app, @RockerSwitchValueChanged);
            app.LabelToggleSwitch = uilabel(app.UIFigure);
            app.LabelToggleSwitch.Text = 'Toggle Switch';
            app.ToggleSwitch = uiswitch(app.UIFigure, 'toggle');
            app.ToggleSwitch.ValueChangedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged);
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end