classdef App1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure                   % UI Figure
        LabelKnob         matlab.ui.control.Label            % Knob
        Knob              matlab.ui.control.Knob             % [0 100]
        NumericEditField  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelDiscreteKnob matlab.ui.control.Label            % Discrete Knob
        DiscreteKnob      matlab.ui.control.DiscreteKnob     % Off, Low, Me...
        EditField         matlab.ui.control.EditField       
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Knob value changed function
        function KnobValueChanged(app)
            value = app.Knob.Value;
            app.NumericEditField.Value = value;
        end

        % Knob value changing function
        function KnobValueChanging(app, event)
            changingValue = event.Value;
            app.NumericEditField.Value = changingValue;
        end

        % DiscreteKnob value changed function
        function DiscreteKnobValueChanged(app)
            value = app.DiscreteKnob.Value;
            if strcmp(value , 'Off')
                app.EditField.Value = 'Off';
            elseif strcmp(value,'Low')
                app.EditField.Value = 'Low';
            elseif strcmp(value , 'Medium')
                app.EditField.Value = 'Medium';
            elseif strcmp(value , 'High')
                app.EditField.Value = 'High';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.LabelKnob = uilabel(app.UIFigure);
            app.LabelKnob.Text = 'Knob';
            app.Knob = uiknob(app.UIFigure, 'continuous');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged);
            app.Knob.ValueChangingFcn = createCallbackFcn(app, @KnobValueChanging, true);
            app.NumericEditField = uieditfield(app.UIFigure, 'numeric');
            app.LabelDiscreteKnob = uilabel(app.UIFigure);
            app.LabelDiscreteKnob.Text = 'Discrete Knob';
            app.DiscreteKnob = uiknob(app.UIFigure, 'discrete');
            app.DiscreteKnob.ValueChangedFcn = createCallbackFcn(app, @DiscreteKnobValueChanged);
            app.EditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = App1()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end